classdef Bucklab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        TabGroup                     matlab.ui.container.TabGroup
        INICIOTab                    matlab.ui.container.Tab
        Image7                       matlab.ui.control.Image
        CHILLBUCKCONVERTERSIMULATIONLabel  matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        BUCKCONVERTERTab             matlab.ui.container.Tab
        WLabel                       matlab.ui.control.Label
        VLabel_2                     matlab.ui.control.Label
        HzLabel_2                    matlab.ui.control.Label
        VLabel                       matlab.ui.control.Label
        SELECCIONETIPODECONTROLADORLabel  matlab.ui.control.Label
        PIDButton                    matlab.ui.control.StateButton
        PIButton                     matlab.ui.control.StateButton
        PButton                      matlab.ui.control.StateButton
        Label_3                      matlab.ui.control.Label
        FUNCTIONLabel                matlab.ui.control.Label
        TRANSFERLabel                matlab.ui.control.Label
        KDLabel                      matlab.ui.control.Label
        KILabel                      matlab.ui.control.Label
        KPLabel                      matlab.ui.control.Label
        Image6                       matlab.ui.control.Image
        SIMULATIONFRECUENCYDropDown  matlab.ui.control.DropDown
        SIMULATIONFRECUENCYLabel     matlab.ui.control.Label
        Image5                       matlab.ui.control.Image
        RIPPLEVDropDown              matlab.ui.control.DropDown
        RIPPLEVDropDownLabel         matlab.ui.control.Label
        Image2_2                     matlab.ui.control.Image
        RIPPLEIDropDown              matlab.ui.control.DropDown
        RIPPLEIDropDownLabel         matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        LOADLabel                    matlab.ui.control.Label
        RIPPLEVLabel                 matlab.ui.control.Label
        RIPPLEiLabel                 matlab.ui.control.Label
        CAPACITORLabel               matlab.ui.control.Label
        ILAVERAGELabel               matlab.ui.control.Label
        INDUCTORLabel                matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        CALCULARButton               matlab.ui.control.Button
        SWICHINGFRECUENCYEditField   matlab.ui.control.NumericEditField
        FRECUENCYLabel               matlab.ui.control.Label
        WATTSEditField               matlab.ui.control.NumericEditField
        WATTSEditFieldLabel          matlab.ui.control.Label
        VINEditField                 matlab.ui.control.NumericEditField
        VINEditFieldLabel            matlab.ui.control.Label
        VOUTEditField                matlab.ui.control.NumericEditField
        VOUTEditFieldLabel           matlab.ui.control.Label
        OFFLINESIMULATIONTab         matlab.ui.container.Tab
        ELMODELOESTADISEADOPARATENERCAMBIOSDEREFERENCIADEL20YLASGRAFICASSEENCUENTRANNORMALIZADASLabel  matlab.ui.control.Label
        ExportarGraficasLabel        matlab.ui.control.Label
        Exportarjpg                  matlab.ui.control.Image
        ExportarDatosLabel           matlab.ui.control.Label
        ExportarData                 matlab.ui.control.Image
        SIMBUTTON                    matlab.ui.control.StateButton
        Image2_3                     matlab.ui.control.Image
        CHILTab                      matlab.ui.container.Tab
        InformacindePinesLabel       matlab.ui.control.Label
        SECARGAELMODELOLabel         matlab.ui.control.Label
        SECARGAELCONTROLADORLabel    matlab.ui.control.Label
        Image5_2                     matlab.ui.control.Image
        CARGAELMODELOYELCONTROLADORLabel  matlab.ui.control.Label
        CORE2                        matlab.ui.control.StateButton
        CORE1                        matlab.ui.control.StateButton
        Image8                       matlab.ui.control.Image
        Image2_4                     matlab.ui.control.Image
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Vg 
        Vout_buck
        Iout_buck
        Pnom 
        f_sw 
        fsim 
        dV 
        dIL
        L_buck 
        C_buck1 
        C_2 
        C_buck
        R 
        R1 
        R2 
        R3
        D_Buck 
        C_pi
        Tsw 
        Tsim
        Kp 
        Ki
        Kd
        Scaling_Voltage 
        Scaling_Current
        TBPRD
        SimulationCont 
        SimulationDisc
        axVout
        axIL
        axDuty
        axPwm
        Gainsimulink
        SimTime 

    end
    
    
    methods (Access = private)
       
            function ok = validarParametros(app, vars)
            ok = true;
            for k = 1:size(vars,1)
                name  = vars{k,1};
                value = vars{k,2};
    
                if isempty(value) || ~isnumeric(value)
                    uialert(app.UIFigure, ...
                        ['⚠ El parámetro "' name '" no está calculado o no es válido.'], ...
                        'Faltan datos');
                    ok = false;
                    return; 
                end
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

         if ~isfield(app, 'axVout') || ~isvalid(app.axVout)
          
            app.axVout = uiaxes(app.OFFLINESIMULATIONTab, 'Position', [35 300 390 250]);
            title(app.axVout,'Voltaje salida'); ylabel(app.axVout,'Vout [P.U]'); grid(app.axVout,'on');
        
            app.axIL = uiaxes(app.OFFLINESIMULATIONTab, 'Position', [450 300 390 250]);
            title(app.axIL,'Corriente Inductor'); ylabel(app.axIL,'IL [P.U]'); grid(app.axIL,'on');
        
            app.axDuty = uiaxes(app.OFFLINESIMULATIONTab, 'Position', [35 50 390 250]);
            title(app.axDuty,'Duty'); ylabel(app.axDuty,'Duty'); xlabel(app.axDuty,'Tiempo (ms)'); grid(app.axDuty,'on');   
        
            app.axPwm = uiaxes(app.OFFLINESIMULATIONTab, 'Position', [450 50 390 250]);
            title(app.axPwm,'PWM'); ylabel(app.axPwm,'PWM'); xlabel(app.axPwm,'Tiempo (ms)'); grid(app.axPwm,'on');
         end

        end

        % Button pushed function: CALCULARButton
        function CALCULARButtonPushed(app, event)


    Vin  = app.VINEditField.Value;        
    Vo   = app.VOUTEditField.Value;        
    Po   = app.WATTSEditField.Value;        
    Fsw  = app.SWICHINGFRECUENCYEditField.Value; 
    app.fsim = app.SIMULATIONFRECUENCYDropDown.Value; 


    switch app.SIMULATIONFRECUENCYDropDown.Value

        case 'x 2'
            app.fsim = 2; 
        case 'x 3'
            app.fsim = 3;
        case 'x 4'
            app.fsim = 4; 
        case 'x 5'
            app.fsim = 5;   
        case 'x 10'
            app.fsim = 10; 
        case 'x 20'
            app.fsim = 20;                  
        case 'x 30'
            app.fsim = 30;
        case 'x 40'
            app.fsim = 40;                  
        case 'x 50'
            app.fsim = 50; 
        case 'x 60'
            app.fsim = 60;                  
        case 'x 70'
            app.fsim = 70;
        case 'x 80'
            app.fsim = 80;                  
        case 'x 90'
            app.fsim = 90;
        otherwise
            app.fsim = 100;
    end
    
    app.D_Buck = Vo / Vin;
    app.Label_2.Text = "D = " + string(round(app.D_Buck,3));

    app.R  = Vo^2 / Po;
    P1 = Po*0.5; app.R1 = Vo^2 / P1;
    P2 = Po*0.6; app.R2 = Vo^2 / P2;
    P3 = Po*0.8; app.R3 = Vo^2 / P3;

    app.Iout_buck = Po / Vo;
    app.Scaling_Voltage = (1/Vo)*(2^12)/(3);
    app.Scaling_Current = (1/app.Iout_buck)*(2^12)/(3);
    app.TBPRD = round(200e6/(2*Fsw));

    app.Tsw  = 1/Fsw;
    app.Tsim = 1/(app.fsim*Fsw);

    app.D_Buck = Vo / Vin;
    app.Label_2.Text = "D = " + string(round(app.D_Buck,3));

    fs_kHz = Fsw / 1e3;
    app.Label.Text = string(fs_kHz) + " kHz";

    Iprom = Po / Vo;
    app.ILAVERAGELabel.Text = "Io = " + string(round(Iprom,3)) + " A";

    app.R = Vo^2 / Po;
    app.LOADLabel.Text = "RL = " + string(round(app.R,2)) + " Ω";

    
    switch app.RIPPLEIDropDown.Value
        case '10%'
            app.dIL = Iprom * 0.10; 
        case '20%'
            app.dIL = Iprom * 0.20;                  
        case '30%'
            app.dIL = Iprom * 0.30;
        otherwise
            app.dIL = Iprom * 0.20;
    end
    app.RIPPLEiLabel.Text = "ΔIL = " + string(round(app.dIL,3)) + " A";            

    switch app.RIPPLEVDropDown.Value
        case '1%'
            app.dV = Vo * 0.01;
        case '5%'
            app.dV = Vo * 0.05;
        case '10%'
            app.dV = Vo * 0.10;
        otherwise
            app.dV = Vo * 0.01;
    end
    app.RIPPLEVLabel.Text = "ΔVout = " + string(round(app.dV,4)) + " V";


    app.L_buck = (Vin - Vo)/(Fsw*app.dIL) * app.D_Buck;
    app.INDUCTORLabel.Text = "L = " + string(sprintf('%.6f',app.L_buck)) + " H";

    app.C_buck1 = ((1/Fsw)*(app.dIL/2))/(8*(app.dV/2));
    ic = app.dIL;
    deltat = 5*(1/Fsw);
    deltav = 0.1*Vo;
    app.C_2 = (ic*deltat)/deltav;
    app.C_buck = app.C_buck1;

    app.CAPACITORLabel.Text = "C = " + string(sprintf('%.6e',app.C_buck)) + " F";

  
        Num = Vin;
        Den = [app.L_buck*app.C_buck1, app.L_buck/app.R1, 1];
        g = tf(Num,Den);

        if app.PButton.Value
            [C,~] = pidtune(g,'P');
        elseif app.PIButton.Value
            [C,~] = pidtune(g,'PI');
        elseif app.PIDButton.Value
            [C,~] = pidtune(g,'PID');
        else
        uialert(app.UIFigure, ...
        'No se seleccionó tipo de controlador', ...
        'Error');
        return;

        end

        app.Kp = C.Kp;
        if isprop(C,'Ki'), app.Ki = C.Ki; else, app.Ki = 0; end
        if isprop(C,'Kd'), app.Kd = C.Kd; else, app.Kd = 0; end

 
        [num, den] = tfdata(g, 'v');  
        
        num_str = poly2str(num, 's');
        den_str = poly2str(den, 's');
        app.Label_3.Text= 'TF = ';
        app.TRANSFERLabel.Text= {num_str,'___________________________'};
        app.FUNCTIONLabel.Text = den_str;
    
        app.KPLabel.Text = "KP = " + string(app.Kp);
        app.KILabel.Text = "KI = " + string(app.Ki);
        app.KDLabel.Text = "KD = " + string(app.Kd);

       
        app.Vg         = Vin;
        app.Vout_buck  = Vo;
        app.Pnom       = Po;
        app.f_sw       = Fsw;
        app.Tsw        = 1/Fsw;

        end

        % Value changed function: SIMBUTTON
        function SIMBUTTONValueChanged(app, event)

    warning('off'); 
    
    if isempty(app.Kp) || isempty(app.Ki)
        uialert(app.UIFigure,'Primero Calcula para obtener parámetros.','Faltan datos');
        return;
    end
    
    disp(['Kp cargado: ', num2str(app.Kp)]);
    disp(['Ki cargado: ', num2str(app.Ki)]);
    disp(['Kd cargado: ', num2str(app.Kd)]);
    disp(['L_buck: ', num2str(app.L_buck)]);
    disp(['C_buck: ', num2str(app.C_buck)]);

    app.Gainsimulink = 60e-3/app.Tsw;
    app.SimTime = app.Gainsimulink*app.Tsw;
    RelativeTolerance = 1e-5;

    mdlCont = 'ContinuosBuck_Offine_Control4';
    mdlDisc = 'DiscreteBuck_Offline_Control4';

    simInCont = Simulink.SimulationInput(mdlCont);
    simInDisc = Simulink.SimulationInput(mdlDisc);
    
    vars = { ...
        'kp', app.Kp; ...
        'ki', app.Ki; ...
        'kd', app.Kd; ...
        'L_buck', app.L_buck; ...
        'C_buck', app.C_buck; ...
        'R', app.R; ...
        'Vg', app.VINEditField.Value; ...
        'Vout_buck', app.Vout_buck; ...
        'Iout_buck', app.Iout_buck; ...
        'Tsw', app.Tsw; ...
        'Tsim', app.Tsim; ...
        'f_sw', double(app.f_sw); ...
        'fsim', app.fsim; ...
        'TBPRD', app.TBPRD; ...
        'SimTime', app.SimTime; ...
        'RelativeTolerance', RelativeTolerance...
        };
    
    for k = 1:size(vars,1)
        name  = vars{k,1};
        value = vars{k,2};
    
        if isempty(value)
            uialert(app.UIFigure, ...
                ['La variable "',name,'" está vacía. Calcula primero.'], ...
                'Error de parámetros');
            return;
        end
    
        if ~isnumeric(value)
            uialert(app.UIFigure, ...
                ['La variable "',name,'" no es numérica. Tipo: ',class(value)], ...
                'Error de tipo');
            return;
        end

        vars{k,2} = double(value);
    end
    

    for k = 1:size(vars,1)
        simInCont = setVariable(simInCont, vars{k,1}, vars{k,2});
        simInDisc = setVariable(simInDisc, vars{k,1}, vars{k,2});
    end
    
    try set_param(mdlCont,'SimulationCommand','stop'); catch, end
    try set_param(mdlDisc,'SimulationCommand','stop'); catch, end
    
    simInCont = simInCont.setModelParameter('StopTime', num2str(app.SimTime));
    simInDisc = simInDisc.setModelParameter('StopTime', num2str(app.SimTime));

    d = uiprogressdlg(app.UIFigure, ...
    'Title','Simulación en curso', ...
    'Message','Ejecutando modelos...', ...
    'Indeterminate','on');
    
    try

        outCont = sim(simInCont, 'ShowProgress', 'on');  
        outDisc = sim(simInDisc, 'ShowProgress', 'on'); 
    

        app.SimulationCont = outCont;
        app.SimulationDisc = outDisc;
    
        d.Message = '✅ Simulación completada';
        pause(1);
    
    catch ME
        close(d);
    
        allMessages = ME.message;
        if ~isempty(ME.cause)
            for k = 1:numel(ME.cause)
                allMessages = [allMessages newline ME.cause{k}.message]; %#ok<AGROW>
            end
        end
        if contains(allMessages,'fixed-step','IgnoreCase',true) || ...
           contains(allMessages,'integer multiple','IgnoreCase',true)
            uialert(app.UIFigure, ...
                ['No se pudo ejecutar la simulación.' newline ...
                 'El tiempo de muestreo no es múltiplo del paso fijo.' newline ...
                 'Ajusta la frecuencia de simulación o el fixed-step.'], ...
                 'Error de Configuración', 'Icon','warning');
        else
            uialert(app.UIFigure, ...
                ['❌ Simulación interrumpida:' newline newline allMessages], ...
                'Error', 'Icon','error');
        end
        return;
    end


    resc = outCont.ResultCont{1}.Values;
    tc = resc.Time;
    datac = resc.Data;
    
    resd = outDisc.ResultDisc{1}.Values;
    td = resd.Time;
    datad = resd.Data;

    voutc     = datac(:,1);
    iLc       = datac(:,2);
    dutyc     = datac(:,3);
    PWMc      = datac(:,4);

    voutd     = datad(:,1);
    iLd       = datad(:,2);
    dutyd     = datad(:,3);
    PWMd      = datad(:,4);
    

    cla(app.axVout);
    plot(app.axVout, tc*1e3, voutc, 'b','LineWidth',1.5); hold(app.axVout,'on');
    plot(app.axVout, td*1e3, voutd, '','LineWidth',1.2);
    hold(app.axVout,'off');
    legend(app.axVout,{'Continuo','Discreto'},'Location','best','AutoUpdate','off');

    cla(app.axIL);
    plot(app.axIL, tc*1e3, iLc, 'b','LineWidth',1.5); hold(app.axIL,'on');
    plot(app.axIL, td*1e3, iLd, 'r','LineWidth',1.2);
    hold(app.axIL,'off');
    legend(app.axIL,{'Continuo','Discreto'},'Location','best','AutoUpdate','off');
    
    cla(app.axDuty);
    plot(app.axDuty, tc*1e3, dutyc, 'b','LineWidth',1.5); hold(app.axDuty,'on');
    plot(app.axDuty, td*1e3, dutyd, 'r','LineWidth',1.2);
    hold(app.axDuty,'off');
    legend(app.axDuty,{'Continuo','Discreto'},'Location','best','AutoUpdate','off');

    cla(app.axPwm);
    plot(app.axPwm, tc*1e3, PWMc, 'b','LineWidth',1.5); hold(app.axPwm,'on');
    plot(app.axPwm, td*1e3, PWMd, 'r','LineWidth',1.2);
    hold(app.axPwm,'off');
    legend(app.axPwm,{'Continuo','Discreto'},'Location','best','AutoUpdate','off');
    xlim(app.axPwm,[24 25]);
    ylim(app.axPwm,[-0.5 1.5]);
    warning('on'); 
        end

        % Value changed function: PButton
        function PButtonValueChanged(app, event)
       colorNormal = [0.50,0.06,0.07];
       colorActivo = [0.1294 1.0 0.0588];
         if app.PButton.Value

            app.PIButton.Value = false;
            app.PIDButton.Value = false;

            app.PButton.BackgroundColor   = colorActivo;
            app.PIButton.BackgroundColor  = colorNormal;
            app.PIDButton.BackgroundColor = colorNormal;
        else
            app.PButton.BackgroundColor = colorNormal;
        end       
        end

        % Value changed function: PIButton
        function PIButtonValueChanged(app, event)
       colorNormal = [0.50,0.06,0.07];
       colorActivo = [0.1294 1.0 0.0588];
            if app.PIButton.Value

                app.PButton.Value = false;
                app.PIDButton.Value = false;

                app.PIButton.BackgroundColor  = colorActivo;
                app.PButton.BackgroundColor   = colorNormal;
                app.PIDButton.BackgroundColor = colorNormal;
            else
                app.PIButton.BackgroundColor = colorNormal;
            end
        end

        % Value changed function: PIDButton
        function PIDButtonValueChanged(app, event)
       colorNormal = [0.50,0.06,0.07];
       colorActivo = [0.1294 1.0 0.0588];
            if app.PIDButton.Value

                app.PIButton.Value = false;
                app.PButton.Value  = false;
                app.PIDButton.BackgroundColor = colorActivo;
                app.PIButton.BackgroundColor  = colorNormal;
                app.PButton.BackgroundColor   = colorNormal;
            else
                app.PIDButton.BackgroundColor = colorNormal;
            end
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
   

infoFig = figure('Name','Introducción a la App', ...
    'Position',[200 200 740 500], ...
    'Color',[0.56,0.08,0.11], ...
    'MenuBar','none', ...
    'ToolBar','none', ...
    'NumberTitle','off', ...
    'Resize','off');


tg = uitabgroup('Parent',infoFig,'Position',[.03 .05 .94 .9]);


t1 = uitab(tg,'Title','Teoría','FontName','Dosis Semibold');

bgPanel1 = uipanel('Parent',t1, ...
    'Position',[0 0 1 1], ...
    'BackgroundColor',[0.87 0.83 0.65]);

p1 = uipanel('Parent',bgPanel1, ...
    'Title','Fundamentos Teóricos del Convertidor Buck', ...
    'FontWeight','bold', ...
    'FontSize',12, ...
    'FontName','Dosis Semibold', ...
    'BackgroundColor',[0.87 0.83 0.65], ...
    'Position',[0.05 0.15 0.9 0.8],'BorderType','none');

uicontrol('Parent',p1,'Style','text', ...
    'String',{...
        'El convertidor Buck es un convertidor reductor de voltaje, ampliamente utilizado en fuentes de alimentación y sistemas de electrónica de potencia. En esta sección se presentan los fundamentos teóricos necesarios para su análisis y diseño:', ...
        '', ...
        '- Se calcula el duty cycle (D), la corriente y el voltaje de salida.', ...
        '- Se determina la resistencia de carga en función de la potencia.', ...
        '- Se analiza el rizado de corriente (ΔIL) y de voltaje (ΔVout).', ...
        '- Se seleccionan los valores de L y C para limitar los rizados.', ...
        '- Se introduce la importancia de los controladores P, PI y PID.'}, ...
    'BackgroundColor',[0.87 0.83 0.65], ...
    'FontSize',11, ...
    'FontName','Dosis Semibold', ...
    'HorizontalAlignment','left', ...
    'Position',[20 20 600 250]);


t2 = uitab(tg,'Title','Simulación','FontName','Dosis Semibold');

bgPanel2 = uipanel('Parent',t2, ...
    'Position',[0 0 1 1], ...
    'BackgroundColor',[0.87 0.83 0.65]);

p2 = uipanel('Parent',bgPanel2, ...
    'Title','Simulación en MATLAB/Simulink', ...
    'FontWeight','bold', ...
    'FontSize',12, ...
    'FontName','Dosis Semibold', ...
    'BackgroundColor',[0.87 0.83 0.65], ...
    'Position',[0.05 0.15 0.9 0.8],'BorderType','none');

uicontrol('Parent',p2,'Style','text', ...
    'String',{...
        'Antes de implementar el sistema en hardware, es fundamental validar la teoría mediante simulación. En esta etapa, el convertidor Buck se modela en MATLAB/Simulink para analizar su comportamiento dinámico y la respuesta bajo diferentes condiciones de operación. Esto permite:', ...
        '', ...
        '- Comprobar que los cálculos teóricos son correctos.', ...
        '- Observar el efecto de variaciones en la carga o la tensión de entrada.', ...
        '- Ajustar parámetros del diseño para mejorar la estabilidad.', ...
        '- Evaluar el desempeño de controladores P, PI y PID sin riesgos.'}, ...
    'BackgroundColor',[0.87 0.83 0.65], ...
    'FontSize',11, ...
    'FontName','Dosis Semibold', ...
    'HorizontalAlignment','left', ...
    'Position',[20 20 600 250]);


t3 = uitab(tg,'Title','Implementación','FontName','Dosis Semibold');

bgPanel3 = uipanel('Parent',t3, ...
    'Position',[0 0 1 1], ...
    'BackgroundColor',[0.87 0.83 0.65]);

p3 = uipanel('Parent',bgPanel3, ...
    'Title','Implementación en Hardware (Launchpad)', ...
    'FontWeight','bold', ...
    'FontSize',12, ...
    'FontName','Dosis Semibold', ...
    'BackgroundColor',[0.87 0.83 0.65], ...
    'Position',[0.05 0.15 0.9 0.8],'BorderType','none');

uicontrol('Parent',p3,'Style','text', ...
    'String',{...
        'La implementación práctica es el paso final que conecta la teoría y la simulación con la realidad. Utilizando el microcontrolador Launchpad, se llevan a cabo pruebas de control y regulación del convertidor Buck. Esta etapa busca:', ...
        '', ...
        '- Programar el microcontrolador con los controladores diseñados.', ...
        '- Configurar periféricos como PWM, ADC y comunicación.', ...
        '- Validar el funcionamiento del sistema en condiciones reales.', ...
        '- Comparar los resultados experimentales con los obtenidos en simulación.', ...
        '- Confirmar que el diseño cumple los objetivos de eficiencia y regulación.'}, ...
    'BackgroundColor',[0.87 0.83 0.65], ...
    'FontSize',11, ...
    'FontName','Dosis Semibold', ...
    'HorizontalAlignment','left', ...
    'Position',[20 20 600 250]);


uicontrol('Parent',infoFig,'Style','pushbutton', ...
    'String','Cerrar', ...
    'FontSize',12, ...
    'FontName','Dosis Semibold', ...
    'Position',[250 50 220 40], ...
    'Callback',@(src,evt) close(infoFig));

        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)

infoFig = uifigure('Name','Información','Position',[400 100 600 500]);
uilabel(infoFig, ...
    'Text','📘 Información Relevante - Parámetros del Buck', ...
    'FontSize',16, ...
    'FontWeight','bold', ...
    'HorizontalAlignment','center', ...
    'Position',[50 450 500 30]);

uitextarea(infoFig, ...
    'Value',{
        '1. VIN [V]'
        '   - Significado: Voltaje de entrada que recibe el convertidor.'
        '   - Importancia: Define la energía disponible para ser convertida. En un buck, siempre debe ser mayor que Vout.'
        ''
        '2. VOUT [V]'
        '   - Significado: Voltaje de salida deseado (el valor al que el buck reduce la entrada).'
        '   - Importancia: Se usa para calcular el ciclo de trabajo (D): D = Vout / Vin'
        ''
        '3. WATTS [W]'
        '   - Significado: Potencia de salida que entrega el convertidor.'
        '   - Importancia: Permite calcular la corriente de salida: Iout = P / Vout'
        '                  Esta corriente es clave para dimensionar los componentes.'
        ''
        '4. SWITCHING FREQUENCY [Hz]'
        '   - Significado: Frecuencia a la que conmuta el transistor (PWM).'
        '   - Importancia: Afecta el tamaño del inductor y capacitor y la eficiencia.'
        ''
        '5. SIMULATION FREQUENCY'
        '   - Significado: Factor de muestreo respecto a la frecuencia de conmutación.'
        '   - Ejemplo: Si fsw = 6 kHz y se selecciona ×50 → f_sim = 300 kHz.'
        ''
        '6. RIPPLE [I]'
        '   - Significado: Corriente de rizado en el inductor (20-30% típico).'
        ''
        '7. RIPPLE [V]'
        '   - Significado: Rizado de tensión en el capacitor (1-20% típico de Vout).'
    }, ...
    'Editable','off', ...
    'FontSize',12, ...
    'FontName','Consolas', ... 
    'Position',[30 70 540 370]); 

uibutton(infoFig, 'push', ...
    'Text','Cerrar', ...
    'FontWeight','bold', ...
    'BackgroundColor',[0.8 0 0], ...
    'FontColor','white', ...
    'Position',[250 20 100 30], ...
    'ButtonPushedFcn', @(btn,event) close(infoFig));


        end

        % Image clicked function: Image8
        function Image8Clicked(app, event)
            web('https://www.ti.com/product/es-mx/TMS320F28379D', '-browser');
        end

        % Value changed function: CORE1
        function CORE1ValueChanged(app, event)

    vars = { ...
        'Vout_buck', app.Vout_buck; ...
        'TBPRD', app.TBPRD; ...
        'f_sw', app.f_sw; ...
        'Tsw', app.Tsw; ...
        'Tsim', app.Tsim; ...
        'kp', app.Kp; ...
        'ki', app.Ki; ...
        'kd', app.Kd; ...
        'Scaling_Voltage', app.Scaling_Voltage; ...
        'Scaling_Current', app.Scaling_Current ...
    };
    if ~app.validarParametros(vars)
        return;
    end

    for k = 1:size(vars,1)
        assignin('base', vars{k,1}, vars{k,2});
    end


    dlg = uiprogressdlg(app.UIFigure,'Title','Subiendo Control (CPU1)',...
        'Message','Compilando...','Indeterminate','on');
    try
        load_system('control.slx');
        rtwbuild('control'); 
        dlg.Message = 'Subiendo a CPU1...';
        close(dlg);
        uialert(app.UIFigure,'✅ Control subido correctamente a CPU1.','Éxito',"Icon","success");
    catch ME
        close(dlg);
        uialert(app.UIFigure,['❌ Error al subir Control: ' ME.message],'Error');
    end
            
        end

        % Value changed function: CORE2
        function CORE2ValueChanged(app, event)

    vars = { ...
        'Scaling_Voltage', app.Scaling_Voltage; ...
        'Scaling_Current', app.Scaling_Current; ...
        'Vg', app.Vg; ...
        'Tsim', app.Tsim; ...
        'R', app.R; ...
        'L_buck', app.L_buck; ...
        'C_buck', app.C_buck ...
    };
    if ~app.validarParametros(vars)
        return;
    end
    for k = 1:size(vars,1)
        assignin('base', vars{k,1}, vars{k,2});
    end

    dlg = uiprogressdlg(app.UIFigure,'Title','Subiendo Modelo (CPU2)',...
        'Message','Compilando...','Indeterminate','on');
    try
        load_system('modelo.slx');
        rtwbuild('modelo'); 
        dlg.Message = 'Subiendo a CPU2...';
        close(dlg);
        uialert(app.UIFigure,'✅ Modelo subido correctamente a CPU2.','Éxito',"Icon","success");
    catch ME
        close(dlg);
        uialert(app.UIFigure,['❌ Error al subir Modelo: ' ME.message],'Error');
    end
            
        end

        % Image clicked function: Image5_2
        function Image5_2Clicked(app, event)

        infoFig = uifigure('Name','Información de Pines',...
            'Position',[500 300 600 450]);
        

        gl = uigridlayout(infoFig,[4 2]);
        gl.RowHeight = {40, '1x', 40, 40};  
        gl.ColumnWidth = {'2x','1x'};
        gl.Padding = [10 10 10 10];
        gl.ColumnSpacing = 10;
        gl.RowSpacing = 10;
        

        lbl = uilabel(gl, ...
            'Text','Mapa de Pines - LaunchPad TMS320F28379D', ...
            'FontSize',16, ...
            'FontWeight','bold', ...
            'HorizontalAlignment','center');
        lbl.Layout.Row = 1;
        lbl.Layout.Column = [1 2];

        data = {
            'PIN 40','PWM','SALIDA CONTROL';
            'PIN 23','ADC','FEEDBACK';
            'PIN 34','PWM','ENTRADA MODELO';
            'PIN 30','DAC A','VOUT';
            'PIN 70','DAC B','IOUT'
        };
        tbl = uitable(gl, ...
            'Data', data, ...
            'ColumnName', {'Pin','Función','Señal'}, ...
            'ColumnWidth', {'auto','auto','auto'}, ...
            'FontSize',12, ...
            'RowStriping','on');
        tbl.Layout.Row = 2;
        tbl.Layout.Column = 1;
        
        img = uiimage(gl, ...
            'ImageSource','launchpad2.png', ...
            'ScaleMethod','fit');
        img.Layout.Row = 2;
        img.Layout.Column = 2;
        
        txt = uilabel(gl, ...
            'Text', { ...
                '⚠ Recuerda conectar el pin 40 al pin 34 y el pin 30 al pin 23,', ...
                'para visualizar en el Osciloscopio conecta las sondas al Vout y al Iout.' ...
            }, ...
            'FontSize',14, ...
            'FontColor',[0.85 0.1 0.1], ... 
            'HorizontalAlignment','center');
        txt.Layout.Row = 3;
        txt.Layout.Column = [1 2];

        btn = uibutton(gl,'push', ...
            'Text','Cerrar', ...
            'FontWeight','bold', ...
            'BackgroundColor',[0.8 0 0], ...
            'FontColor','white', ...
            'ButtonPushedFcn', @(btn,event) close(infoFig));
        btn.Layout.Row = 4;
        btn.Layout.Column = [1 2];

        end

        % Image clicked function: ExportarData
        function ExportarDataClicked(app, event)

            
    if isempty(app.SimulationCont) || isempty(app.SimulationDisc)
        uialert(app.UIFigure,'Primero ejecuta la simulación.','Faltan datos');
        return;
    end

    simResults = struct();
    
    simResults.tc = app.SimulationCont.ResultCont{1}.Values.Time;
    simResults.td = app.SimulationDisc.ResultDisc{1}.Values.Time;

    datac = app.SimulationCont.ResultCont{1}.Values.Data;
    simResults.voutc     = datac(:,1);
    simResults.iLc       = datac(:,2);
    simResults.dutyc     = datac(:,3);
    simResults.pwmc      = datac(:,4);
    datad = app.SimulationDisc.ResultDisc{1}.Values.Data;
    simResults.voutd     = datad(:,1);
    simResults.iLd       = datad(:,2);
    simResults.dutyd     = datad(:,3);
    simResults.pwmd      = datad(:,4);

    simResults.Kp       = app.Kp;
    simResults.Ki       = app.Ki;
    simResults.Kd       = app.Kd;
    simResults.L_buck   = app.L_buck;
    simResults.C_buck   = app.C_buck;
    simResults.Vg       = app.VINEditField.Value;
    simResults.Vout_buck= app.Vout_buck;
    simResults.Iout_buck= app.Iout_buck;
    simResults.Tsw      = app.Tsw;
    simResults.Tsim     = app.Tsim;
    simResults.f_sw     = app.f_sw;
    simResults.fsim     = app.fsim;

    [file, path] = uiputfile('SimulacionResultados.mat','Guardar resultados como');
    if isequal(file,0) || isequal(path,0)
        uialert(app.UIFigure,'Guardado cancelado','Aviso');
    else
        save(fullfile(path,file),'simResults');
        uialert(app.UIFigure,'Resultados guardados correctamente, encontrara los parametros en una estructura .mat','Éxito',"Icon","info");
    end
        end

        % Image clicked function: Exportarjpg
        function ExportarjpgClicked(app, event)

    if isempty(app.SimulationCont) || isempty(app.SimulationDisc)
        uialert(app.UIFigure,'Primero ejecuta la simulación.','Faltan datos');
        return;
    end

    folder = uigetdir(pwd, 'Selecciona carpeta para guardar la gráfica');
    if folder == 0
        uialert(app.UIFigure,'Guardado cancelado','Aviso');
        return;
    end


    try
        f = figure('Color','w','Position',[100 100 1200 800]);

        axesList = {app.axVout, app.axIL, app.axDuty, app.axPwm};
        axesTitles = {'Voltaje de salida','Corriente del Inductor','Duty Cycle','PWM'};

        for k = 1:length(axesList)
            axUI = axesList{k};
            axSub = subplot(length(axesList),1,k); 
            for c = flip(axUI.Children)'  
                plot(axSub, c.XData, c.YData, 'Color', c.Color, 'LineWidth', c.LineWidth);
                hold(axSub,'on');
            end
            hold(axSub,'off');

            title(axSub, axesTitles{k}, 'FontWeight','bold');
            xlabel(axSub, axUI.XLabel.String);
            ylabel(axSub, axUI.YLabel.String);

            lgd = legend(axUI);
            if ~isempty(lgd)
                legend(axSub, lgd.String,'Location','best');
            end

            grid(axSub,'on');
        end
        linkaxes(findall(f,'Type','axes'),'x');

        saveas(f, fullfile(folder,'SimulacionResultados.png'));
        close(f);

        uialert(app.UIFigure,'Gráfica guardada correctamente','Éxito',"Icon","success");

    catch ME
        uialert(app.UIFigure,['Error guardando gráfica: ' ME.message],'Error');
    end
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
          web(' https://www.picuino.com/es/control-pid.html', '-browser');       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.INICIOTab = uitab(app.TabGroup);
            app.INICIOTab.Title = 'INICIO';
            app.Image4 = uiimage(app.INICIOTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Resource', 'fondo.png');
            app.Image2 = uiimage(app.INICIOTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Resource', 'universidad-surcolombiana.png');
            app.CHILLBUCKCONVERTERSIMULATIONLabel = uilabel(app.INICIOTab);
            app.CHILLBUCKCONVERTERSIMULATIONLabel.Text = {'CHILL BUCK'; ' CONVERTER SIMULATION'};
            app.Image7 = uiimage(app.INICIOTab);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'Resource', 'Qustion.png');
            app.BUCKCONVERTERTab = uitab(app.TabGroup);
            app.BUCKCONVERTERTab.Title = 'BUCK CONVERTER';
            app.VOUTEditFieldLabel = uilabel(app.BUCKCONVERTERTab);
            app.VOUTEditFieldLabel.Text = 'VOUT ';
            app.VOUTEditField = uieditfield(app.BUCKCONVERTERTab, 'numeric');
            app.VINEditFieldLabel = uilabel(app.BUCKCONVERTERTab);
            app.VINEditFieldLabel.Text = 'VIN ';
            app.VINEditField = uieditfield(app.BUCKCONVERTERTab, 'numeric');
            app.WATTSEditFieldLabel = uilabel(app.BUCKCONVERTERTab);
            app.WATTSEditFieldLabel.Text = 'WATTS ';
            app.WATTSEditField = uieditfield(app.BUCKCONVERTERTab, 'numeric');
            app.FRECUENCYLabel = uilabel(app.BUCKCONVERTERTab);
            app.FRECUENCYLabel.Text = {'SWICHING '; 'FRECUENCY'};
            app.SWICHINGFRECUENCYEditField = uieditfield(app.BUCKCONVERTERTab, 'numeric');
            app.CALCULARButton = uibutton(app.BUCKCONVERTERTab, 'push');
            app.CALCULARButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULARButton.Text = 'CALCULAR';
            app.Image = uiimage(app.BUCKCONVERTERTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resource', 'buckpng-removebg-preview.png');
            app.INDUCTORLabel = uilabel(app.BUCKCONVERTERTab);
            app.INDUCTORLabel.Text = 'INDUCTOR';
            app.ILAVERAGELabel = uilabel(app.BUCKCONVERTERTab);
            app.ILAVERAGELabel.Text = 'IL AVERAGE';
            app.CAPACITORLabel = uilabel(app.BUCKCONVERTERTab);
            app.CAPACITORLabel.Text = 'CAPACITOR';
            app.RIPPLEiLabel = uilabel(app.BUCKCONVERTERTab);
            app.RIPPLEiLabel.Text = 'RIPPLE [ i ]';
            app.RIPPLEVLabel = uilabel(app.BUCKCONVERTERTab);
            app.RIPPLEVLabel.Text = 'RIPPLE [V]';
            app.LOADLabel = uilabel(app.BUCKCONVERTERTab);
            app.LOADLabel.Text = 'LOAD';
            app.Label_2 = uilabel(app.BUCKCONVERTERTab);
            app.Label_2.Text = '';
            app.Label = uilabel(app.BUCKCONVERTERTab);
            app.Label.Text = '';
            app.RIPPLEIDropDownLabel = uilabel(app.BUCKCONVERTERTab);
            app.RIPPLEIDropDownLabel.Text = 'RIPPLE [ I ]';
            app.RIPPLEIDropDown = uidropdown(app.BUCKCONVERTERTab);
            app.RIPPLEIDropDown.Items = {'10%', '20%', '30%'};
            app.RIPPLEIDropDown.Value = '30%';
            app.Image2_2 = uiimage(app.BUCKCONVERTERTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'Resource', 'Usco_logo.png');
            app.RIPPLEVDropDownLabel = uilabel(app.BUCKCONVERTERTab);
            app.RIPPLEVDropDownLabel.Text = 'RIPPLE [ V ]';
            app.RIPPLEVDropDown = uidropdown(app.BUCKCONVERTERTab);
            app.RIPPLEVDropDown.Items = {'1%', '5%', '10%'};
            app.RIPPLEVDropDown.Value = '5%';
            app.Image5 = uiimage(app.BUCKCONVERTERTab);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Resource', 'Qustion.png');
            app.SIMULATIONFRECUENCYLabel = uilabel(app.BUCKCONVERTERTab);
            app.SIMULATIONFRECUENCYLabel.Text = {'SIMULATION   '; ' FRECUENCY'};
            app.SIMULATIONFRECUENCYDropDown = uidropdown(app.BUCKCONVERTERTab);
            app.SIMULATIONFRECUENCYDropDown.Items = {'x 2', 'x 3', 'x 4', 'x 5', 'x 10', 'x 20', 'x 30', 'x 40', 'x 50', 'x 60', 'x 70', 'x 80', 'x 90', 'x 100'};
            app.SIMULATIONFRECUENCYDropDown.Value = 'x 50';
            app.Image6 = uiimage(app.BUCKCONVERTERTab);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Resource', 'PID-removebg-preview.png');
            app.KPLabel = uilabel(app.BUCKCONVERTERTab);
            app.KPLabel.Text = 'KP';
            app.KILabel = uilabel(app.BUCKCONVERTERTab);
            app.KILabel.Text = 'KI';
            app.KDLabel = uilabel(app.BUCKCONVERTERTab);
            app.KDLabel.Text = 'KD';
            app.TRANSFERLabel = uilabel(app.BUCKCONVERTERTab);
            app.TRANSFERLabel.Text = {'TRANSFER '; ''};
            app.FUNCTIONLabel = uilabel(app.BUCKCONVERTERTab);
            app.FUNCTIONLabel.Text = 'FUNCTION';
            app.Label_3 = uilabel(app.BUCKCONVERTERTab);
            app.Label_3.Text = '';
            app.PButton = uibutton(app.BUCKCONVERTERTab, 'state');
            app.PButton.ValueChangedFcn = createCallbackFcn(app, @PButtonValueChanged, true);
            app.PButton.Text = 'P';
            app.PIButton = uibutton(app.BUCKCONVERTERTab, 'state');
            app.PIButton.ValueChangedFcn = createCallbackFcn(app, @PIButtonValueChanged, true);
            app.PIButton.Text = 'PI';
            app.PIDButton = uibutton(app.BUCKCONVERTERTab, 'state');
            app.PIDButton.ValueChangedFcn = createCallbackFcn(app, @PIDButtonValueChanged, true);
            app.PIDButton.Text = 'PID';
            app.SELECCIONETIPODECONTROLADORLabel = uilabel(app.BUCKCONVERTERTab);
            app.SELECCIONETIPODECONTROLADORLabel.Text = 'SELECCIONE TIPO DE CONTROLADOR';
            app.VLabel = uilabel(app.BUCKCONVERTERTab);
            app.VLabel.Text = ' [ V ]';
            app.HzLabel_2 = uilabel(app.BUCKCONVERTERTab);
            app.HzLabel_2.Text = ' [ Hz ]';
            app.VLabel_2 = uilabel(app.BUCKCONVERTERTab);
            app.VLabel_2.Text = ' [ V ]';
            app.WLabel = uilabel(app.BUCKCONVERTERTab);
            app.WLabel.Text = ' [ W ]';
            app.OFFLINESIMULATIONTab = uitab(app.TabGroup);
            app.OFFLINESIMULATIONTab.Title = 'OFFLINE SIMULATION';
            app.Image2_3 = uiimage(app.OFFLINESIMULATIONTab);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'Resource', 'Usco_logo.png');
            app.SIMBUTTON = uibutton(app.OFFLINESIMULATIONTab, 'state');
            app.SIMBUTTON.ValueChangedFcn = createCallbackFcn(app, @SIMBUTTONValueChanged, true);
            app.SIMBUTTON.Text = 'RUN SIMULATION';
            app.ExportarData = uiimage(app.OFFLINESIMULATIONTab);
            app.ExportarData.ImageClickedFcn = createCallbackFcn(app, @ExportarDataClicked, true);
            app.ExportarData.ImageSource = fullfile(pathToMLAPP, 'Resource', 'save.png');
            app.ExportarDatosLabel = uilabel(app.OFFLINESIMULATIONTab);
            app.ExportarDatosLabel.Text = 'Exportar Datos';
            app.Exportarjpg = uiimage(app.OFFLINESIMULATIONTab);
            app.Exportarjpg.ImageClickedFcn = createCallbackFcn(app, @ExportarjpgClicked, true);
            app.Exportarjpg.ImageSource = fullfile(pathToMLAPP, 'Resource', 'imagen.png');
            app.ExportarGraficasLabel = uilabel(app.OFFLINESIMULATIONTab);
            app.ExportarGraficasLabel.Text = 'Exportar Graficas';
            app.ELMODELOESTADISEADOPARATENERCAMBIOSDEREFERENCIADEL20YLASGRAFICASSEENCUENTRANNORMALIZADASLabel = uilabel(app.OFFLINESIMULATIONTab);
            app.ELMODELOESTADISEADOPARATENERCAMBIOSDEREFERENCIADEL20YLASGRAFICASSEENCUENTRANNORMALIZADASLabel.Text = {'EL MODELO ESTA DISEÑADO '; 'PARA TENER CAMBIOS DE REFERENCIA '; 'DEL 20% Y LAS GRAFICAS'; ' SE ENCUENTRAN NORMALIZADAS.'};
            app.CHILTab = uitab(app.TabGroup);
            app.CHILTab.Title = 'CHIL';
            app.Image2_4 = uiimage(app.CHILTab);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'Resource', 'Usco_logo.png');
            app.Image8 = uiimage(app.CHILTab);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image8Clicked, true);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'Resource', 'launchpad.png');
            app.CORE1 = uibutton(app.CHILTab, 'state');
            app.CORE1.ValueChangedFcn = createCallbackFcn(app, @CORE1ValueChanged, true);
            app.CORE1.Text = 'CPU 1';
            app.CORE2 = uibutton(app.CHILTab, 'state');
            app.CORE2.ValueChangedFcn = createCallbackFcn(app, @CORE2ValueChanged, true);
            app.CORE2.Text = 'CPU 2';
            app.CARGAELMODELOYELCONTROLADORLabel = uilabel(app.CHILTab);
            app.CARGAELMODELOYELCONTROLADORLabel.Text = 'CARGA EL MODELO Y EL CONTROLADOR';
            app.Image5_2 = uiimage(app.CHILTab);
            app.Image5_2.ImageClickedFcn = createCallbackFcn(app, @Image5_2Clicked, true);
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'Resource', 'Qustion.png');
            app.SECARGAELCONTROLADORLabel = uilabel(app.CHILTab);
            app.SECARGAELCONTROLADORLabel.Text = 'SE CARGA EL CONTROLADOR';
            app.SECARGAELMODELOLabel = uilabel(app.CHILTab);
            app.SECARGAELMODELOLabel.Text = 'SE CARGA EL MODELO';
            app.InformacindePinesLabel = uilabel(app.CHILTab);
            app.InformacindePinesLabel.Text = 'Información de Pines';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bucklab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end